classdef normal_distribution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        YaxisEditField                  matlab.ui.control.EditField
        YaxisEditFieldLabel             matlab.ui.control.Label
        XaxisEditField                  matlab.ui.control.EditField
        XaxisEditFieldLabel             matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        save                            matlab.ui.control.Button
        PressonNormaldistributionplottogeneratenewfigurewithitLabel  matlab.ui.control.Label
        distfunction                    matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        Youshouldprovideatleast30elementsLabel  matlab.ui.control.Label
        loading                         matlab.ui.control.Button
        DataHigher                      matlab.ui.control.NumericEditField
        ProbabilitydataXEditField_2Label  matlab.ui.control.Label
        DataLess                        matlab.ui.control.NumericEditField
        ProbabilitydataXEditFieldLabel  matlab.ui.control.Label
        DataEqual                       matlab.ui.control.NumericEditField
        ProbabilitydataXLabel           matlab.ui.control.Label
        DataInput                       matlab.ui.control.NumericEditField
        InputXEditFieldLabel            matlab.ui.control.Label
        Mean                            matlab.ui.control.NumericEditField
        MeanEditFieldLabel              matlab.ui.control.Label
        Sigma                           matlab.ui.control.NumericEditField
        StandarddeviationEditFieldLabel  matlab.ui.control.Label
        plot_of_nd                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Container=1; % container for Variation
        items=1; % Description
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        
        function results = func(app)
            title(app.plot_of_nd,app.TitleEditField.Value)
            xlabel(app.plot_of_nd,app.XaxisEditField.Value)
            ylabel(app.plot_of_nd,app.YaxisEditField.Value)
            sigmaval = app.Sigma.Value;
            meanval = app.Mean.Value;
            input = app.DataInput.Value;
            f = 1/(sigmaval * sqrt(2*pi));
            fp= @(x)f* exp((-(x-meanval).^2)/(2*sigmaval.^2));
            Xax = linspace(meanval-3.5*sigmaval,meanval+3.5*sigmaval,800);
            
            plot(app.plot_of_nd,Xax,fp(Xax));
            xlim(app.plot_of_nd,[Xax(1) Xax(end)])
            hold(app.plot_of_nd,'on');
            area(app.plot_of_nd,Xax(1):sigmaval/10:input,fp(Xax(1):sigmaval/10:input),'FaceColor','m','FaceAlpha',.3);
            hold(app.plot_of_nd,'off');

            app.DataLess.Value=integral(fp,-Inf,input);
            app.DataHigher.Value=integral(fp,input,Inf);
            app.DataEqual.Value=fp(input);
            y_cumulative = ones(1,length(Xax));
            for i = 1:length(Xax)
            y_cumulative(i) = integral(fp,-Inf,Xax(i));
            end
            
            results = [Xax;fp(Xax);y_cumulative;integral(fp,-Inf,input)*ones(1,length(Xax))];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            grid(app.plot_of_nd,'minor')
            app.Switch.Enable='off'; 
            app.Sigma.Value=1;
            func(app);
        end

        % Value changed function: Mean
        function MeanValueChanged(app, event)
            func(app);
        end

        % Value changed function: Sigma
        function SigmaValueChanged(app, event)
            if app.Sigma.Value == 0
            app.Sigma.Value = 0.1;
            end
            func(app);
        end

        % Value changed function: DataInput
        function DataInputValueChanged(app, event)
            func(app);
            MeanValueChanged(app);
        end

        % Button pushed function: loading
        function loadingPushed(app, event)
            try
            Y = uiimport;
            X=cell2mat(struct2cell(Y));
            app.Mean.Value=sum(X)/length(X);
            Variance = 0;
            for i = 1:length(X)
            Variance = Variance + (X(i)-app.Mean.Value)^2;
            end
            app.Container=Variance;
            app.items=length(X);
            app.Sigma.Value=sqrt(app.Container/length(X));
            app.Switch.Enable='on';
            func(app);
            catch
                disp('canceled')
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Population"
                k = app.items;
            else
                k= app.items -1;
            end
            app.Sigma.Value=sqrt(app.Container/k);
            disp(app.Container/k)
            func(app);
        end

        % Button down function: plot_of_nd
        function plot_of_ndButtonDown(app, event)
           try
            close("Normal Distribution plt")
           catch
           end
            k=func(app);
            Xax=k(1,:);
            y=k(2,:);
            figure('Name',"Normal Distribution plt");
            plot(Xax,y)
            hold on;
            area(Xax(Xax<=app.DataInput.Value),y(Xax<=app.DataInput.Value),'FaceColor','m','FaceAlpha',.3);
            grid minor;
            title(app.TitleEditField.Value)
            xlabel(app.XaxisEditField.Value)
            ylabel(app.YaxisEditField.Value)
            hold off;

        end

        % Button pushed function: distfunction
        function distfunctionButtonPushed(app, event)
            try
            close("Cumulative Distribution Fnc")
            catch
            end
            k = func(app);
            t = k(1,:);
            fp = k(2,:);
            y = k(3,:);
            current_val =k(4,:);
            figure('Name',"Cumulative Distribution Fnc");
            plot(t,y,'LineWidth',2);
            grid minor;
            hold on;
            plot(app.DataInput.Value*ones(1,2),[0 y(t==app.DataInput.Value)],'--','Color',[0,0,0,0.3])
            plot([t(2), app.DataInput.Value],[current_val(1), current_val(1)],'--','Color',[0,0,0,0.3]);
            plot([app.DataInput.Value, app.DataInput.Value],[0, current_val(1)],'--','Color',[0,0,0,0.3]);
            axis([t(1),t(end),0,1])
            title(['Cumulative distribution function of ',app.TitleEditField.Value])
            xlabel(app.XaxisEditField.Value)
            ylabel(['Probability(arguments<= ',app.XaxisEditField.Value,')'])
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            k=func(app);
            distfunctionButtonPushed(app,event);
            saveas(gcf,"Cumulative_Distribution_Fnc.png")
            plot_of_ndButtonDown(app,event);
            saveas(gcf,"Normal_Distribution_plt.png")
        end

        % Value changed function: XaxisEditField
        function XaxisEditFieldValueChanged(app, event)
            func(app);
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            func(app);
        end

        % Value changed function: YaxisEditField
        function YaxisEditFieldValueChanged(app, event)
            func(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.plot_of_nd = uiaxes(app.UIFigure);
            title(app.plot_of_nd, 'Normal distribution')
            xlabel(app.plot_of_nd, 'X')
            ylabel(app.plot_of_nd, 'φ(x)')
            zlabel(app.plot_of_nd, 'Z')
            app.plot_of_nd.ButtonDownFcn = createCallbackFcn(app, @plot_of_ndButtonDown, true);
            app.StandarddeviationEditFieldLabel = uilabel(app.UIFigure);
            app.StandarddeviationEditFieldLabel.Text = 'Standard deviation';
            app.Sigma = uieditfield(app.UIFigure, 'numeric');
            app.Sigma.ValueDisplayFormat = '%.5f';
            app.Sigma.ValueChangedFcn = createCallbackFcn(app, @SigmaValueChanged, true);
            app.MeanEditFieldLabel = uilabel(app.UIFigure);
            app.MeanEditFieldLabel.Text = 'Mean ';
            app.Mean = uieditfield(app.UIFigure, 'numeric');
            app.Mean.ValueDisplayFormat = '%.5f';
            app.Mean.ValueChangedFcn = createCallbackFcn(app, @MeanValueChanged, true);
            app.InputXEditFieldLabel = uilabel(app.UIFigure);
            app.InputXEditFieldLabel.Text = 'Input X';
            app.DataInput = uieditfield(app.UIFigure, 'numeric');
            app.DataInput.ValueDisplayFormat = '%.5f';
            app.DataInput.ValueChangedFcn = createCallbackFcn(app, @DataInputValueChanged, true);
            app.ProbabilitydataXLabel = uilabel(app.UIFigure);
            app.ProbabilitydataXLabel.Text = 'Density(X)';
            app.DataEqual = uieditfield(app.UIFigure, 'numeric');
            app.DataEqual.ValueDisplayFormat = '%.5f';
            app.DataEqual.Editable = 'off';
            app.ProbabilitydataXEditFieldLabel = uilabel(app.UIFigure);
            app.ProbabilitydataXEditFieldLabel.Text = 'Probability(data<X)';
            app.DataLess = uieditfield(app.UIFigure, 'numeric');
            app.DataLess.ValueDisplayFormat = '%.5f';
            app.DataLess.Editable = 'off';
            app.ProbabilitydataXEditField_2Label = uilabel(app.UIFigure);
            app.ProbabilitydataXEditField_2Label.Text = 'Probability(data>X)';
            app.DataHigher = uieditfield(app.UIFigure, 'numeric');
            app.DataHigher.ValueDisplayFormat = '%.5f';
            app.DataHigher.Editable = 'off';
            app.loading = uibutton(app.UIFigure, 'push');
            app.loading.ButtonPushedFcn = createCallbackFcn(app, @loadingPushed, true);
            app.loading.Text = 'Load data';
            app.Youshouldprovideatleast30elementsLabel = uilabel(app.UIFigure);
            app.Youshouldprovideatleast30elementsLabel.Text = '*You should provide at least 30 elements';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Population', 'Sample'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Population';
            app.distfunction = uibutton(app.UIFigure, 'push');
            app.distfunction.ButtonPushedFcn = createCallbackFcn(app, @distfunctionButtonPushed, true);
            app.distfunction.Text = {'Generate'; 'Cumulative distribution function '};
            app.PressonNormaldistributionplottogeneratenewfigurewithitLabel = uilabel(app.UIFigure);
            app.PressonNormaldistributionplottogeneratenewfigurewithitLabel.Text = 'Press on "Normal distribution" plot to generate new figure with it.';
            app.save = uibutton(app.UIFigure, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Text = 'Save image';
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.TitleEditField.Value = 'Normal distribution';
            app.XaxisEditFieldLabel = uilabel(app.UIFigure);
            app.XaxisEditFieldLabel.Text = 'Xaxis';
            app.XaxisEditField = uieditfield(app.UIFigure, 'text');
            app.XaxisEditField.ValueChangedFcn = createCallbackFcn(app, @XaxisEditFieldValueChanged, true);
            app.XaxisEditField.Value = 'x';
            app.YaxisEditFieldLabel = uilabel(app.UIFigure);
            app.YaxisEditFieldLabel.Text = 'Yaxis';
            app.YaxisEditField = uieditfield(app.UIFigure, 'text');
            app.YaxisEditField.ValueChangedFcn = createCallbackFcn(app, @YaxisEditFieldValueChanged, true);
            app.YaxisEditField.Value = 'Density(x)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = normal_distribution

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end